classdef IRIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InteractiveResourceImpactSimulationJoshuaAngeUIFigure  matlab.ui.Figure
        Background                  matlab.ui.control.Image
        INTERACTIVERESOURCEIMPACTSIMULATIONLabel  matlab.ui.control.Label
        VersionNumber               matlab.ui.control.Label
        Logo                        matlab.ui.control.Image
        GraphicsPanel               matlab.ui.container.Panel
        Main                        matlab.ui.control.UIAxes
        LogPanel                    matlab.ui.container.Panel
        ParametersPanel             matlab.ui.container.TabGroup
        InitialConditionsTab        matlab.ui.container.Tab
        Button                      matlab.ui.control.Button
        PodInformationTab           matlab.ui.container.Tab
        EnvironmentalConditionsTab  matlab.ui.container.Tab
        ProgramDataTab              matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            folder = pwd;
            file = 'PTHeight_CraterTest';
            load(strcat(folder,'\data\outputs\',file,'.mat'));
            
            % MAIN GRAPH
            hold (app.Main,"on");
            grid (app.Main,"minor");
            view (app.Main,3);
            colormap(app.Main,"gray")
            xlim(app.Main,[0 L_domain]);
            ylim(app.Main,[0 L_domain]);
            zlim(app.Main,[0 L_range]);
            surfc(app.Main,L_x, L_y, L_z,'EdgeAlpha',0.1,'FaceAlpha',1,'DisplayName','Surface Topology') % Mesh Plot
            
             for iit = 1:i_max
                 if isempty(b{iit}) == 0
                    % Trajectory
                    fplot3(app.Main,b{iit}.x_i,b{iit}.y_i,b{iit}.z_i,[0 double(b{iit}.t_f_i)],'DisplayName','Trajectory','lineWidth',1,'Color','black');
                    % Velocities
                    quiver3(app.Main,b{iit}.u_i(1,1),b{iit}.u_i(1,2),b{iit}.u_i(1,3),b{iit}.u_i(2,1),b{iit}.u_i(2,2),b{iit}.u_i(2,3),'lineWidth',1,'Color','green','MaxHeadSize',0.5,'DisplayName','Launch Velocity');
                    quiver3(app.Main,b{iit}.v_i(1,1),b{iit}.v_i(1,2),b{iit}.v_i(1,3),b{iit}.v_i(2,1),b{iit}.v_i(2,2),b{iit}.v_i(2,3),'lineWidth',1,'Color','red','MaxHeadSize',0.5,'DisplayName','Impact Velocity');
                    % Impact
                    plot3(app.Main,b{iit}.S_i(1),b{iit}.S_i(2),b{iit}.S_i(3),'Marker','s','DisplayName','Impact Point');
                    % Spin Quaternion
                    quiver3(app.Main,b{iit}.q_i(1,1),b{iit}.q_i(1,2),b{iit}.q_i(1,3),b{iit}.q_i(2,1),b{iit}.q_i(2,2),b{iit}.q_i(2,3),'lineWidth',0.5,'Color','black','MaxHeadSize',0.1,'DisplayName','Air Spin Quaternion','LineStyle','--');
            
                    % Pod
                    surf(app.Main,double(b{iit}.X_i), double(b{iit}.Y_i), double(b{iit}.Z_i),'DisplayName','Pod','facealpha',0.75,'edgealpha',0.5)
                    % Center
                    plot3(app.Main,double(b{iit}.C_i(1)),double(b{iit}.C_i(2)),double(b{iit}.C_i(3)),'Marker','o','DisplayName','Center Point');
                 end
                 for jit = 1:j_max
                     if isempty(s{iit,jit}) == 0
                        % Traced Segment Path
                        quiver3(app.Main,s{iit,jit}.O_ij(1,1),s{iit,jit}.O_ij(1,2),s{iit,jit}.O_ij(1,3),s{iit,jit}.O_ij(2,1),s{iit,jit}.O_ij(2,2),s{iit,jit}.O_ij(2,3),'lineWidth',1,'Color','black','MaxHeadSize',0.5,'DisplayName','Traced Path Segment');
                        quiver3(app.Main,s{iit,jit}.N_ij(1,1),s{iit,jit}.N_ij(1,2),s{iit,jit}.N_ij(1,3),s{iit,jit}.N_ij(2,1),s{iit,jit}.N_ij(2,2),s{iit,jit}.N_ij(2,3),'lineWidth',2,'Color','red','MaxHeadSize',0.5,'DisplayName','Normal Vector');
                        % Velocity
                        quiver3(app.Main,s{iit,jit}.v_ij(1,1),s{iit,jit}.v_ij(1,2),s{iit,jit}.v_ij(1,3),s{iit,jit}.v_ij(2,1),s{iit,jit}.v_ij(2,2),s{iit,jit}.v_ij(2,3),'lineWidth',0.5,'Color','green','MaxHeadSize',0.5,'DisplayName','Initial Velocity');
                        quiver3(app.Main,s{iit,jit}.V_ij(1,1),s{iit,jit}.V_ij(1,2),s{iit,jit}.V_ij(1,3),s{iit,jit}.V_ij(2,1),s{iit,jit}.V_ij(2,2),s{iit,jit}.V_ij(2,3),'lineWidth',0.5,'Color','green','MaxHeadSize',0.5,'DisplayName','Final Velocity');
                        % Spin Quaternion
                        quiver3(app.Main,s{iit,jit}.p_ij(1,1),s{iit,jit}.p_ij(1,2),s{iit,jit}.p_ij(1,3),s{iit,jit}.p_ij(2,1),s{iit,jit}.p_ij(2,2),s{iit,jit}.p_ij(2,3),'lineWidth',0.5,'Color','red','MaxHeadSize',0.1,'DisplayName','Influence Quaternion','LineStyle','--');
                        quiver3(app.Main,s{iit,jit}.q_ij(1,1),s{iit,jit}.q_ij(1,2),s{iit,jit}.q_ij(1,3),s{iit,jit}.q_ij(2,1),s{iit,jit}.q_ij(2,2),s{iit,jit}.q_ij(2,3),'lineWidth',0.5,'Color','black','MaxHeadSize',0.1,'DisplayName','Ground Spin Quaternion 1','LineStyle','--');
                        quiver3(app.Main,s{iit,jit}.VQ_ij(1,1),s{iit,jit}.VQ_ij(1,2),s{iit,jit}.VQ_ij(1,3),s{iit,jit}.VQ_ij(2,1),s{iit,jit}.VQ_ij(2,2),s{iit,jit}.VQ_ij(2,3),'lineWidth',0.5,'Color','green','MaxHeadSize',0.1,'DisplayName','Ground Spin Direction','LineStyle','--');
                        quiver3(app.Main,s{iit,jit}.Q_ij(1,1),s{iit,jit}.Q_ij(1,2),s{iit,jit}.Q_ij(1,3),s{iit,jit}.Q_ij(2,1),s{iit,jit}.Q_ij(2,2),s{iit,jit}.Q_ij(2,3),'lineWidth',0.5,'Color','blue','MaxHeadSize',0.1,'DisplayName','Ground Spin Quaternion 2','LineStyle','--');
                        quiver3(app.Main,s{iit,jit}.p_ij_direction(1,1),s{iit,jit}.p_ij_direction(1,2),s{iit,jit}.p_ij_direction(1,3),s{iit,jit}.p_ij_direction(2,1),s{iit,jit}.p_ij_direction(2,2),s{iit,jit}.p_ij_direction(2,3),'lineWidth',0.5,'Color','yellow','MaxHeadSize',0.1,'DisplayName','Influence Direction','LineStyle','--');
            
                        % Gravity
                        quiver3(app.Main,s{iit,jit}.A_g_ij(1,1),s{iit,jit}.A_g_ij(1,2),s{iit,jit}.A_g_ij(1,3),s{iit,jit}.A_g_ij(2,1),s{iit,jit}.A_g_ij(2,2),s{iit,jit}.A_g_ij(2,3),'lineWidth',2,'Color','red','MaxHeadSize',0.5,'DisplayName','Gravity Application','LineStyle',':');
                        quiver3(app.Main,s{iit,jit}.VG_ij(1,1),s{iit,jit}.VG_ij(1,2),s{iit,jit}.VG_ij(1,3),s{iit,jit}.VG_ij(2,1),s{iit,jit}.VG_ij(2,2),s{iit,jit}.VG_ij(2,3),'lineWidth',0.5,'Color','yellow','MaxHeadSize',0.1,'DisplayName','Gravity Application Direction','LineStyle','--');
                        % Normal
                        quiver3(app.Main,s{iit,jit}.A_n_ij(1,1),s{iit,jit}.A_n_ij(1,2),s{iit,jit}.A_n_ij(1,3),s{iit,jit}.A_n_ij(2,1),s{iit,jit}.A_n_ij(2,2),s{iit,jit}.A_n_ij(2,3),'lineWidth',1,'Color','black','MaxHeadSize',0.5,'DisplayName','Normal Application','LineStyle',':');
                        % Elasticity
                        quiver3(app.Main,s{iit,jit}.A_e_ij(1,1),s{iit,jit}.A_e_ij(1,2),s{iit,jit}.A_e_ij(1,3),s{iit,jit}.A_e_ij(2,1),s{iit,jit}.A_e_ij(2,2),s{iit,jit}.A_e_ij(2,3),'lineWidth',1,'Color','green','MaxHeadSize',0.5,'DisplayName','Elastic Application','LineStyle',':');
                        % Friction
                        quiver3(app.Main,s{iit,jit}.A_f_ij(1,1),s{iit,jit}.A_f_ij(1,2),s{iit,jit}.A_f_ij(1,3),s{iit,jit}.A_f_ij(2,1),s{iit,jit}.A_f_ij(2,2),s{iit,jit}.A_f_ij(2,3),'lineWidth',2,'Color','yellow','MaxHeadSize',0.5,'DisplayName','Friction Application','LineStyle',':');
                        % Rotation
                        quiver3(app.Main,s{iit,jit}.A_r_ij(1,1),s{iit,jit}.A_r_ij(1,2),s{iit,jit}.A_r_ij(1,3),s{iit,jit}.A_r_ij(2,1),s{iit,jit}.A_r_ij(2,2),s{iit,jit}.A_r_ij(2,3),'lineWidth',1,'Color','blue','MaxHeadSize',0.5,'DisplayName','Rotation Application','LineStyle',':');
            
                        % Pod
                        surf(app.Main,double(s{iit,jit}.Xn_ij), double(s{iit,jit}.Yn_ij), double(s{iit,jit}.Zn_ij),'DisplayName','Pod','facealpha',0.75,'edgealpha',0.5)
                        % Center Point
                        plot3(app.Main,double(s{iit,jit}.Cn_ij(1)),double(s{iit,jit}.Cn_ij(2)),double(s{iit,jit}.Cn_ij(3)),'Marker','s','DisplayName','Impact Point');
                     end
                 end
             end
             hold (app.Main,"off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure = uifigure('Visible', 'off');
            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure.Name = 'Interactive Resource Impact Simulation - Joshua Ange';
            app.Background = uiimage(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Background.ImageSource = 'Blue_Background.png';
            app.INTERACTIVERESOURCEIMPACTSIMULATIONLabel = uilabel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.INTERACTIVERESOURCEIMPACTSIMULATIONLabel.Text = 'INTERACTIVE RESOURCE IMPACT SIMULATION';
            app.VersionNumber = uilabel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.VersionNumber.Text = '1.5.0';
            app.Logo = uiimage(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Logo.ImageSource = 'IRIS_White.png';
            app.GraphicsPanel = uipanel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Main = uiaxes(app.GraphicsPanel);
            title(app.Main, 'Title')
            xlabel(app.Main, 'X')
            ylabel(app.Main, 'Y')
            app.LogPanel = uipanel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.ParametersPanel = uitabgroup(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.InitialConditionsTab = uitab(app.ParametersPanel);
            app.InitialConditionsTab.Title = 'Initial Conditions';
            app.Button = uibutton(app.InitialConditionsTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PodInformationTab = uitab(app.ParametersPanel);
            app.PodInformationTab.Title = 'Pod Information';
            app.EnvironmentalConditionsTab = uitab(app.ParametersPanel);
            app.EnvironmentalConditionsTab.Title = 'Environmental Conditions';
            app.ProgramDataTab = uitab(app.ParametersPanel);
            app.ProgramDataTab.Title = 'Program Data';
            % Show the figure after all components are created
            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure)
        end
    end
end